classdef DropLLetCounter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProgressSlider                 matlab.ui.control.Slider
        AcquisitonframerateEditField   matlab.ui.control.NumericEditField
        AcquisitonframerateEditFieldLabel  matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel        matlab.ui.control.Label
        STARTCOUNTINGButton            matlab.ui.control.Button
        EditField3                     matlab.ui.control.NumericEditField
        EditField2_2                   matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        SelectedareaEditField_3        matlab.ui.control.NumericEditField
        SelectedareaEditField_3Label   matlab.ui.control.Label
        EditField2                     matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        EditFiedEditField              matlab.ui.control.NumericEditField
        SelectedareaEditField          matlab.ui.control.NumericEditField
        SelectedareaEditFieldLabel     matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Image_2                        matlab.ui.control.Image
        ProgressLabel                  matlab.ui.control.Label
        CurrentframeEditField          matlab.ui.control.NumericEditField
        CurrentframeEditFieldLabel     matlab.ui.control.Label
        TotalnumberofframeEditField    matlab.ui.control.NumericEditField
        TotalnumberofframeEditFieldLabel  matlab.ui.control.Label
        ResultsLabel                   matlab.ui.control.Label
        SelectborderofdropletButton    matlab.ui.control.Button
        Select1dropletfromvideoButton  matlab.ui.control.Button
        VideoPropertiesLabel           matlab.ui.control.Label
        FileSelectionLabel             matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        SelectdropletvideoEditField    matlab.ui.control.EditField
        SelectdropletvideoEditFieldLabel  matlab.ui.control.Label
        DropLLetCounterLabel           matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Acq_framerate; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path] = uigetfile({'*.avi';'*.mp4'},'Select video file');
            
            %create full name with path
            fullname = fullfile(path, file);

            %display file path and name
            app.SelectdropletvideoEditField.Value = fullname;
            
            %display total number of frames             mov = VideoReader(fullname);

            number_of_frames = mov.NumFrames;
            app.TotalnumberofframeEditField.Value = number_of_frames;
            app.ProgressSlider.Limits = [0, number_of_frames];
            app.ProgressSlider.MinorTicks = [];
            app.ProgressSlider.MajorTicks = [0, number_of_frames *0.1, number_of_frames*0.2, number_of_frames*0.3, number_of_frames*0.4, number_of_frames*0.5, number_of_frames*0.6, number_of_frames*0.7, number_of_frames*0.8, number_of_frames*0.9, number_of_frames];

            figure(app.UIFigure);
        end

        % Value changed function: SelectdropletvideoEditField
        function SelectdropletvideoEditFieldValueChanged(app, event)
            value = app.SelectdropletvideoEditField.Value;
            
        end

        % Value changed function: TotalnumberofframeEditField
        function TotalnumberofframeEditFieldValueChanged(app, event)
            value = app.TotalnumberofframeEditField.Value;
            
        end

        % Button pushed function: Select1dropletfromvideoButton
        function Select1dropletfromvideoButtonPushed(app, event)
            filename = app.SelectdropletvideoEditField.Value;
            mov = VideoReader(filename);
            show_first_frame = readFrame(mov); %show first frame 
            [J, rect1] = imcrop(show_first_frame); % choose area for counting (whole droplet) 

            app.SelectedareaEditField.Value = rect1(1);
            app.EditFiedEditField.Value = rect1(2);
            app.EditField.Value = rect1(3);
            app.EditField2.Value = rect1(4);


            cropped_frame_1 = imcrop(show_first_frame, rect1); % crop the chosen area
            
            app.Image.ImageSource = cropped_frame_1;
            imshow(app.Image.ImageSource);
            figure(app.UIFigure);
        end

        % Callback function
        function ImageClicked(app, event)
          
        end

        % Callback function
        function ImageClicked2(app, event)
            
        end

        % Button pushed function: SelectborderofdropletButton
        function SelectborderofdropletButtonPushed(app, event)
            
            cropped_frame_1 = app.Image.ImageSource;
            [J, rect2] = imcrop(cropped_frame_1); %crop the image again to select 1 border of droplet
            app.SelectedareaEditField_3.Value = rect2(1);
            app.EditField_2.Value = rect2(2);
            app.EditField2_2.Value = rect2(3);
            app.EditField3.Value = rect2(4);


            cropped_frame_2 = imcrop(cropped_frame_1, rect2); %crop the image
            app.Image_2.ImageSource = cropped_frame_2;
            imshow(app.Image_2.ImageSource);
            figure(app.UIFigure);
        end

        % Value changed function: SelectedareaEditField
        function SelectedareaEditFieldValueChanged(app, event)
            value = app.SelectedareaEditField.Value;
            
        end

        % Value changed function: EditFiedEditField
        function EditFiedEditFieldValueChanged(app, event)
            value = app.EditFiedEditField.Value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            value = app.EditField2.Value;
            
        end

        % Value changed function: SelectedareaEditField_3
        function SelectedareaEditField_3ValueChanged(app, event)
            value = app.SelectedareaEditField_3.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            
        end

        % Value changed function: EditField2_2
        function EditField2_2ValueChanged(app, event)
            value = app.EditField2_2.Value;
            
        end

        % Value changed function: EditField3
        function EditField3ValueChanged(app, event)
            value = app.EditField3.Value;
            
        end

        % Button pushed function: STARTCOUNTINGButton
        function STARTCOUNTINGButtonPushed(app, event)
            brightness_values =zeros(1, app.TotalnumberofframeEditField.Value); %new list for brightness values
            i = 1;
            filename = app.SelectdropletvideoEditField.Value;
            mov = VideoReader(filename);

            %reading video frame by frame
            while hasFrame(mov)  
                app.CurrentframeEditField.Value = i; %showing progress
                app.ProgressSlider.Value = i;
                drawnow
               

                %getiing rects
                rect1 = [app.SelectedareaEditField.Value, app.EditFiedEditField.Value, app.EditField.Value, app.EditField2.Value];
                rect2 = [app.SelectedareaEditField_3.Value, app.EditField_2.Value, app.EditField2_2.Value, app.EditField3.Value];


                % image processing
                frame = readFrame(mov); % open frame
                frame = rgb2gray(frame); %convert frame into grayscale 
                frame = imbinarize(frame, "adaptive",'ForegroundPolarity','dark','Sensitivity',0.4); %convert to black and white

                frame = imcrop(frame, rect1); %crop the image according to 1st selection
                frame = imcrop(frame, rect2); %crop the image according to 2nd selection

                %Counting brightness in selected area 
                brightness = sum(frame, "all" );
    
                %creating list for futher plotting
                brightness_values(i)=brightness;
                i = i + 1;

            end

            %set values on axis x and y 
            x_axis = 1:app.TotalnumberofframeEditField.Value;
            y_axis = brightness_values; 
            plot(app.UIAxes, x_axis(1:100), -y_axis(2:101));

            %count droplets detected as peaks
            [pks1,locs1] = findpeaks(-y_axis, x_axis); %plot is inverted, peaks correspond to black color
            

               %%%% toto nefunguje 
            time = app.TotalnumberofframeEditField.Value/app.AcquisitonframerateEditField.Value; 
            frequency = length(pks1)/time;
            app.FrequencyEditField.Value = frequency;

            figure(app.UIFigure);

        end

        % Value changed function: CurrentframeEditField
        function CurrentframeEditFieldValueChanged(app, event)
            value = app.CurrentframeEditField.Value;
            
        end

        % Callback function
        function AcquisitonframerateEditFieldValueChanging(app, event)
            changingValue = app.AcquisitonframerateEditField.Value;

        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            
        end

        % Value changed function: AcquisitonframerateEditField
        function AcquisitonframerateEditFieldValueChanged(app, event)
            value = app.AcquisitonframerateEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'First 100 frames')
            xlabel(app.UIAxes, 'frame')
            ylabel(app.UIAxes, 'Brightness')
            zlabel(app.UIAxes, 'Z')
            app.DropLLetCounterLabel = uilabel(app.UIFigure);
            app.DropLLetCounterLabel.Text = 'DropLLet Counter';
            app.SelectdropletvideoEditFieldLabel = uilabel(app.UIFigure);
            app.SelectdropletvideoEditFieldLabel.Text = 'Select droplet video';
            app.SelectdropletvideoEditField = uieditfield(app.UIFigure, 'text');
            app.SelectdropletvideoEditField.ValueChangedFcn = createCallbackFcn(app, @SelectdropletvideoEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FileSelectionLabel = uilabel(app.UIFigure);
            app.FileSelectionLabel.Text = 'File Selection';
            app.VideoPropertiesLabel = uilabel(app.UIFigure);
            app.VideoPropertiesLabel.Text = 'Video Properties';
            app.Select1dropletfromvideoButton = uibutton(app.UIFigure, 'push');
            app.Select1dropletfromvideoButton.ButtonPushedFcn = createCallbackFcn(app, @Select1dropletfromvideoButtonPushed, true);
            app.Select1dropletfromvideoButton.Text = 'Select 1 droplet from video';
            app.SelectborderofdropletButton = uibutton(app.UIFigure, 'push');
            app.SelectborderofdropletButton.ButtonPushedFcn = createCallbackFcn(app, @SelectborderofdropletButtonPushed, true);
            app.SelectborderofdropletButton.Text = 'Select border of droplet';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.TotalnumberofframeEditFieldLabel = uilabel(app.UIFigure);
            app.TotalnumberofframeEditFieldLabel.Text = 'Total number of frame';
            app.TotalnumberofframeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalnumberofframeEditField.ValueDisplayFormat = '%.0f';
            app.TotalnumberofframeEditField.ValueChangedFcn = createCallbackFcn(app, @TotalnumberofframeEditFieldValueChanged, true);
            app.CurrentframeEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentframeEditFieldLabel.Text = 'Current frame';
            app.CurrentframeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentframeEditField.ValueDisplayFormat = '%.0f';
            app.CurrentframeEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentframeEditFieldValueChanged, true);
            app.ProgressLabel = uilabel(app.UIFigure);
            app.ProgressLabel.Text = 'Progress';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.SelectedareaEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedareaEditFieldLabel.Text = 'Selected area';
            app.SelectedareaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectedareaEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedareaEditFieldValueChanged, true);
            app.EditFiedEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditFiedEditField.ValueChangedFcn = createCallbackFcn(app, @EditFiedEditFieldValueChanged, true);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.SelectedareaEditField_3Label = uilabel(app.UIFigure);
            app.SelectedareaEditField_3Label.Text = 'Selected area';
            app.SelectedareaEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.SelectedareaEditField_3.ValueChangedFcn = createCallbackFcn(app, @SelectedareaEditField_3ValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField2_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_2.ValueChangedFcn = createCallbackFcn(app, @EditField2_2ValueChanged, true);
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.ValueChangedFcn = createCallbackFcn(app, @EditField3ValueChanged, true);
            app.STARTCOUNTINGButton = uibutton(app.UIFigure, 'push');
            app.STARTCOUNTINGButton.ButtonPushedFcn = createCallbackFcn(app, @STARTCOUNTINGButtonPushed, true);
            app.STARTCOUNTINGButton.Text = 'START COUNTING';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.ValueDisplayFormat = '%.0f';
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.AcquisitonframerateEditFieldLabel = uilabel(app.UIFigure);
            app.AcquisitonframerateEditFieldLabel.Text = 'Acquisiton framerate';
            app.AcquisitonframerateEditField = uieditfield(app.UIFigure, 'numeric');
            app.AcquisitonframerateEditField.Limits = [0 Inf];
            app.AcquisitonframerateEditField.ValueDisplayFormat = '%.0f';
            app.AcquisitonframerateEditField.ValueChangedFcn = createCallbackFcn(app, @AcquisitonframerateEditFieldValueChanged, true);
            app.ProgressSlider = uislider(app.UIFigure);
            app.ProgressSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.ProgressSlider.MajorTickLabels = {'0% ', '10%', '20%', '30%', '40%', '50%', '60%', '70%', '80%', '90%', '100%'};
            app.ProgressSlider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DropLLetCounter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end